classdef StarIDGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        StarIDLamp           matlab.ui.control.Lamp
        StarIDLampLabel      matlab.ui.control.Label
        ConnectLamp          matlab.ui.control.Lamp
        ConnectLampLabel     matlab.ui.control.Label
        SaveLogButton        matlab.ui.control.Button
        LogTextArea          matlab.ui.control.TextArea
        LogTextAreaLabel     matlab.ui.control.Label
        StartStarIDButton    matlab.ui.control.Button
        ServerConnectButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property; % Description
        client;
        StarMat;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ServerConnectButton
        function ServerConnectButtonPushed(app, event)
            if isa(app.client,'tcpclient') == 0
                app.client = TCPClient('localhost', 10000);
            end
            lamptemp = isa(app.client,'tcpclient');
            if lamptemp == 1
                app.ConnectLamp.Color = [0 1 0];
            end
        end

        % Button pushed function: StartStarIDButton
        function StartStarIDButtonPushed(app, event)
           if isa(app.client,'tcpclient') == 1
                app.StarMat = read(app.client,app.client.NumBytesAvailable,"uint16");
                im = image(app.StarMat);
                disp(app.StarMat);
                tempimg =  grs2rgb(app.StarMat);
                app.Image.ImageSource = tempimg;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ServerConnectButton = uibutton(app.UIFigure, 'push');
            app.ServerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ServerConnectButtonPushed, true);
            app.ServerConnectButton.Text = 'ServerConnect';
            app.StartStarIDButton = uibutton(app.UIFigure, 'push');
            app.StartStarIDButton.ButtonPushedFcn = createCallbackFcn(app, @StartStarIDButtonPushed, true);
            app.StartStarIDButton.Text = 'Start StarID';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.SaveLogButton = uibutton(app.UIFigure, 'push');
            app.SaveLogButton.Text = 'Save Log';
            app.ConnectLampLabel = uilabel(app.UIFigure);
            app.ConnectLampLabel.Text = 'Connect';
            app.ConnectLamp = uilamp(app.UIFigure);
            app.StarIDLampLabel = uilabel(app.UIFigure);
            app.StarIDLampLabel.Text = 'StarID';
            app.StarIDLamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'orion.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StarIDGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end